classdef ProjMotionMasterFileCommented < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GraphPanel                      matlab.ui.container.Panel
        Domain090Label                  matlab.ui.control.Label
        TextArea_7                      matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ProjectileMotionSimulatorLabel  matlab.ui.control.Label
        TimeTraveledLabel               matlab.ui.control.Label
        MaximumHeightLabel              matlab.ui.control.Label
        TotalDistanceLabel              matlab.ui.control.Label
        GravitySimulationDropDown       matlab.ui.control.DropDown
        GravitySimulationDropDownLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        LaunchangledegreesEditField     matlab.ui.control.NumericEditField
        LaunchangledegreesLabel         matlab.ui.control.Label
        InitialVelocitymsEditField      matlab.ui.control.NumericEditField
        InitialVelocitymsLabel          matlab.ui.control.Label
        BacktoEquationsButton           matlab.ui.control.Button
        QuizButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        FinalPanel                      matlab.ui.container.Panel
        TextArea_9                      matlab.ui.control.TextArea
        GreatJobLabel                   matlab.ui.control.Label
        LetterGradeLabel                matlab.ui.control.Label
        GradeLabel                      matlab.ui.control.Label
        scoreLabel                      matlab.ui.control.Label
        YourScoreLabel                  matlab.ui.control.Label
        QuizPanel                       matlab.ui.container.Panel
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        Pleasespecifyallanswerstotwodecimalsex275Label  matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Q1Tab                           matlab.ui.container.Tab
        question_1                      matlab.ui.control.Label
        Answer1x                        matlab.ui.control.NumericEditField
        Answer1y                        matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        Question1Label                  matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        Q2Tab                           matlab.ui.container.Tab
        question_2                      matlab.ui.control.Label
        Answer2x                        matlab.ui.control.NumericEditField
        Answer2y                        matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        Question2Label                  matlab.ui.control.Label
        Q3Tab                           matlab.ui.container.Tab
        question_3                      matlab.ui.control.Label
        Answer3m                        matlab.ui.control.NumericEditField
        XLabel_3                        matlab.ui.control.Label
        Question3Label                  matlab.ui.control.Label
        Q4Tab                           matlab.ui.container.Tab
        question_4                      matlab.ui.control.Label
        Answer4m                        matlab.ui.control.NumericEditField
        XLabel_11                       matlab.ui.control.Label
        Question4Label                  matlab.ui.control.Label
        Q5Tab                           matlab.ui.container.Tab
        question_5                      matlab.ui.control.Label
        Answer5s                        matlab.ui.control.NumericEditField
        XLabel_12                       matlab.ui.control.Label
        Question5Label                  matlab.ui.control.Label
        Q6Tab                           matlab.ui.container.Tab
        question_6                      matlab.ui.control.Label
        Answer6s                        matlab.ui.control.NumericEditField
        XLabel_13                       matlab.ui.control.Label
        Question6Label                  matlab.ui.control.Label
        Q7Tab                           matlab.ui.container.Tab
        question_7                      matlab.ui.control.Label
        Answer7m                        matlab.ui.control.NumericEditField
        XLabel_14                       matlab.ui.control.Label
        Question7Label                  matlab.ui.control.Label
        Q8Tab                           matlab.ui.container.Tab
        question_8                      matlab.ui.control.Label
        Answer8m                        matlab.ui.control.NumericEditField
        XLabel_15                       matlab.ui.control.Label
        Question8Label                  matlab.ui.control.Label
        Q9Tab                           matlab.ui.container.Tab
        question_9                      matlab.ui.control.Label
        Answer9h                        matlab.ui.control.NumericEditField
        Answer9t                        matlab.ui.control.NumericEditField
        Answer9d                        matlab.ui.control.NumericEditField
        YLabel_12                       matlab.ui.control.Label
        YLabel_9                        matlab.ui.control.Label
        XLabel_9                        matlab.ui.control.Label
        Question9Label                  matlab.ui.control.Label
        Q10Tab                          matlab.ui.container.Tab
        question_10                     matlab.ui.control.Label
        Answer10h                       matlab.ui.control.NumericEditField
        Answer10t                       matlab.ui.control.NumericEditField
        Answer10d                       matlab.ui.control.NumericEditField
        YLabel_14                       matlab.ui.control.Label
        YLabel_13                       matlab.ui.control.Label
        XLabel_16                       matlab.ui.control.Label
        Question10Label                 matlab.ui.control.Label
        EquationsPanel                  matlab.ui.container.Panel
        ContinuetoSimulationButton      matlab.ui.control.Button
        OverviewTabGroup                matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        Image10                         matlab.ui.control.Image
        OverviewTitle                   matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        OverviewText                    matlab.ui.control.TextArea
        OverviewNext                    matlab.ui.control.Button
        InitialVariablesTab             matlab.ui.container.Tab
        OverviewText_2                  matlab.ui.control.TextArea
        OverviewTitle_2                 matlab.ui.control.EditField
        InitialVariablesNext            matlab.ui.control.Button
        InitialVariablesPrevious        matlab.ui.control.Button
        EquationsTab                    matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        EquationsTitle                  matlab.ui.control.EditField
        EquationsText                   matlab.ui.control.TextArea
        EquationsNext                   matlab.ui.control.Button
        EquationsPrevious               matlab.ui.control.Button
        ComponentsTab                   matlab.ui.container.Tab
        Image9                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        VelocityTitle                   matlab.ui.control.EditField
        ComponentsText                  matlab.ui.control.TextArea
        ComponentsNext                  matlab.ui.control.Button
        ComponentsPrevious              matlab.ui.control.Button
        TextArea_8                      matlab.ui.control.TextArea
        HeightTab                       matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        HeightTitle                     matlab.ui.control.EditField
        TextArea_4                      matlab.ui.control.TextArea
        HeightText                      matlab.ui.control.TextArea
        HeightNext                      matlab.ui.control.Button
        HeightPrevious                  matlab.ui.control.Button
        TimeTab                         matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        TimeTitle                       matlab.ui.control.EditField
        TextArea_5                      matlab.ui.control.TextArea
        TimeText                        matlab.ui.control.TextArea
        TimeNext                        matlab.ui.control.Button
        TimePrevious                    matlab.ui.control.Button
        DistanceTab                     matlab.ui.container.Tab
        DistanceNext                    matlab.ui.control.Button
        Image8                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        DistanceTitle                   matlab.ui.control.EditField
        TextArea_6                      matlab.ui.control.TextArea
        DistanceText                    matlab.ui.control.TextArea
        DistancePrevious                matlab.ui.control.Button
        VelocityGraphTab                matlab.ui.container.Tab
        VelocityGraphPrevious           matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        LoadGraphButton                 matlab.ui.control.Button
        VelocityGraphNext               matlab.ui.control.Button
        VelocityGraphPlot               matlab.ui.control.UIAxes
        AngleGraphTab                   matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        LoadGraphButton_2               matlab.ui.control.Button
        AngleGraphPrevious              matlab.ui.control.Button
        AngleGraphNext                  matlab.ui.control.Button
        AngleGraphPlot                  matlab.ui.control.UIAxes
        GravityGraphTab                 matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        LoadGraphButton_3               matlab.ui.control.Button
        GravityGraphPrevious            matlab.ui.control.Button
        GravityGraphPlot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
 
        %Launch Sim Variables
            IV = 0 %Initial Velocity
            LA = 0 %Launch Angle
            GC = 0 %Gravity Constant
            time = 0

        %Launch Example Arrays
            ex_IV_arr = [5, 10, 15] %example Initial Velocity array
            ex_LA_arr = [30, 45, 75] %example Launch Angle array
            ex_GC_arr = [9.81, 3.71, 1.63] %example Gravity Constant array

        %Quiz Answers
            ansArray = [17.32, 10, 3.96, 14.13, 9.29, 14.72, 0.57, 4.37, 9.6, 18.85, 2, 8, 120.47, 3.43, 0.7, 6.59]
            score = 0
            grade = 0

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            %Makes Equations Panel visible on start up and other panels
            %invisible
            app.EquationsPanel.Visible = "on";
            app.EquationsPanel.Enable = "on";
            app.GraphPanel.Visible = "off";
            app.GraphPanel.Enable = "off";
            app.QuizPanel.Visible = "off";
            app.QuizPanel.Enable = "off";
            app.FinalPanel.Visible = "off";
            app.FinalPanel.Enable = "off";

            %Sets the window to a fixed size (to compensate for resizing
            %issues when fullscreen)
            app.EquationsPanel.Position = [1,1,1280,720];
            app.GraphPanel.Position = [1,1,1280,720];
            app.QuizPanel.Position = [1,1,1280,720];
            app.FinalPanel.Position = [1,1,1280,720];
        end

        % Button pushed function: ContinuetoSimulationButton
        function next1(app, event)
            %Advances to Graph Panel by swapping visibility
            app.EquationsPanel.Visible = "off";
            app.EquationsPanel.Enable = "off";
            app.GraphPanel.Visible = "on";
            app.GraphPanel.Enable = "on";
        end

        % Button pushed function: BacktoEquationsButton
        function back1(app, event)
            %Returns to Equations Panel by swapping visibility
            app.EquationsPanel.Visible = "on";
            app.EquationsPanel.Enable = "on";
            app.GraphPanel.Visible = "off";
            app.GraphPanel.Enable = "off";
        end

        % Button pushed function: QuizButton
        function next2(app, event)
            %Advances to Quiz Panel by swapping visibility
            app.GraphPanel.Visible = "off";
            app.GraphPanel.Enable = "off";
            app.QuizPanel.Visible = "on";
            app.QuizPanel.Enable = "on";
        end

        % Value changed function: Answer10d, Answer10h, Answer10t, 
        % ...and 13 other components
        function quizAnswer(app, event)
            %Enables/Disables Submit Button by checking if any answer was
            %left blank
            if isempty(app.Answer1x.Value) || isempty(app.Answer1y.Value) || isempty(app.Answer2x.Value) || isempty(app.Answer2y.Value) || isempty(app.Answer3m.Value) || isempty(app.Answer4m.Value) || isempty(app.Answer5s.Value) || isempty(app.Answer6s.Value) || isempty(app.Answer7m.Value) || isempty(app.Answer8m.Value) || isempty(app.Answer9d.Value) || isempty(app.Answer9h.Value) || isempty(app.Answer9t.Value) || isempty(app.Answer10d.Value) || isempty(app.Answer10h.Value) || isempty(app.Answer10t)
                app.SubmitButton.Enable = "off";
            else
                app.SubmitButton.Enable = "on";
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            %"Great Job!" message is invisible by default
            app.GreatJobLabel.Visible = "off";

            %Advances to Final Panel by swapping visibility
            app.QuizPanel.Visible = "off";
            app.QuizPanel.Enable = "off";
            app.FinalPanel.Visible = "on";
            app.FinalPanel.Enable = "on";

            %This array stores the user's answers
            editArray = [app.Answer1x.Value, app.Answer1y.Value, app.Answer2x.Value, app.Answer2y.Value, app.Answer3m.Value, app.Answer4m.Value, app.Answer5s.Value, app.Answer6s.Value, app.Answer7m.Value, app.Answer8m.Value, app.Answer9h.Value, app.Answer9t.Value, app.Answer9d.Value, app.Answer10h.Value, app.Answer10t.Value, app.Answer10d.Value];

            %Checks if each answer was within a range of the correct answer
            %by + or - 0.1 and adds 1 to score if within range
            for i = 1:length(editArray)
                if app.ansArray(i) + 0.1 >= editArray(i) && app.ansArray(i) - 0.1 <= editArray(i)
                    app.score = app.score + 1;
                end
            end

            %Displays final score out of 16
            app.scoreLabel.Text = string(app.score) + "/16";

            %Calculates grade as percentage
            app.grade = round(app.score / 16 * 100, 2);

            %Displays letter grade based on score, makes "Great Job!"
            %message visible if score is a B or higher
            if app.grade >= 90
                app.LetterGradeLabel.Text = app.grade + "% A";
                app.GreatJobLabel.Visible = "on";
            elseif app.grade >= 80
                app.LetterGradeLabel.Text = app.grade + "% B";
                app.GreatJobLabel.Visible = "on";
            elseif app.grade >= 70
                app.LetterGradeLabel.Text = app.grade + "% C";
            elseif app.grade >= 60
                app.LetterGradeLabel.Text = app.grade + "% D";
            else
                app.LetterGradeLabel.Text = app.grade + "% F";
            end


        end

        % Button pushed function: OverviewNext
        function PushOverviewNext(app, event)
            %These buttons are pretty self explanitory, they just advance
            %you to the next tab
            app.OverviewTabGroup.SelectedTab = app.InitialVariablesTab;
        end

        % Button pushed function: InitialVariablesPrevious
        function PushInitialVariablesPrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.OverviewTab;
        end

        % Button pushed function: InitialVariablesNext
        function PushInitialVariablesNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.EquationsTab;
        end

        % Callback function
        function PushInitialVariablesLoadGraphs(app, event)
            %This one jumps to the velocity tab, though
            app.OverviewTabGroup.SelectedTab = app.VelocityGraphTab;
        end

        % Button pushed function: EquationsNext
        function PushEquationsNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.ComponentsTab;
        end

        % Button pushed function: EquationsPrevious
        function PushEquationsPrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.InitialVariablesTab;
        end

        % Button pushed function: ComponentsNext
        function PushComponentsNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.HeightTab;
        end

        % Button pushed function: ComponentsPrevious
        function PushComponentsPrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.EquationsTab;
        end

        % Button pushed function: HeightNext
        function PushHeightNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.TimeTab;
        end

        % Button pushed function: HeightPrevious
        function PushHeightPrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.ComponentsTab;
        end

        % Button pushed function: TimeNext
        function PushTimeNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.DistanceTab;
        end

        % Button pushed function: TimePrevious
        function PushTimePrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.HeightTab;
        end

        % Button pushed function: DistancePrevious
        function PushDistancePrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.TimeTab;
        end

        % Button pushed function: VelocityGraphNext
        function PushVelocityGraphNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.AngleGraphTab;
        end

        % Button pushed function: AngleGraphPrevious
        function PushAngleGraphPrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.VelocityGraphTab;
        end

        % Button pushed function: AngleGraphNext
        function PushAngleGraphNext(app, event)
            app.OverviewTabGroup.SelectedTab = app.GravityGraphTab;
        end

        % Button pushed function: GravityGraphPrevious
        function PushGravityGraphPrevious(app, event)
            app.OverviewTabGroup.SelectedTab = app.AngleGraphTab;
        end

        % Callback function
        function PushGravityGraphClose(app, event)
            app.OverviewTabGroup.SelectedTab = app.InitialVariablesTab;
        end

        % Value changed function: InitialVelocitymsEditField
        function InitialVelocitymsEditFieldValueChanged(app, event)
            app.IV = app.InitialVelocitymsEditField.Value;
        end

        % Value changed function: LaunchangledegreesEditField
        function LaunchangledegreesEditFieldValueChanged(app, event)
            app.LA = app.LaunchangledegreesEditField.Value;         
        end

        % Callback function: SimulateButton, UIAxes
        function SimulateButtonPushed(app, event)
            %Disables the simulate button when pushed as it originally could
            %be pressed while a simulation was already happening which
            %would play all of them one at a time each time the button was pushed
            app.SimulateButton.Enable = "off";

            %Determines Gravity Constant based on the drop down menu
            switch app.GravitySimulationDropDown.Value
                case 'Earth (9.81 m/s^2)'
                    app.GC = 9.81;
                case 'Moon (1.63 m/s^2)'
                    app.GC = 1.63;
                case 'Mars (3.71 m/s^2)'
                    app.GC = 3.71;
                case 'Jupiter (24.79 m/s^2)'
                    app.GC = 24.79;
                otherwise
                    app.GC = 0.62;
            end

            %Calculate Horizontal and Vertical Components
            Vx = app.IV * cos(deg2rad(app.LA));
            Vy = app.IV * sin(deg2rad(app.LA));

            %Create time array
            app.time = Time_Function(app.IV,app.LA,app.GC);
            t = 0:0.05:app.time;

            %Create x and y arrays
            x = Vx .* t;
            y = (Vy .* t) - (0.5 * app.GC .* t.^2);

            %Determine max values to appropriately size graph
            xmax = Vx * app.time;
            ymax = (Vy^2) / (2*app.GC);

            %Plots path of trajectory
            plot(app.UIAxes,x,y)

            %Resize Graph
            xlim(app.UIAxes, [0 xmax])
            ylim(app.UIAxes, [0 ymax])

            %Holds graph to retain trajectory
            hold (app.UIAxes, 'on');

            %Creates the point that will move along the trajectory, "-or"
            %means: "-" a solid line, "o" a circle, "r" color red
            h = plot(app.UIAxes,x(1),y(1),"-or");

            %Moves the point along the trajectory in real time
            for i = 2:length(t)
              if y(i) >= 0
                     set(h,'Xdata',x(i),'YData',y(i))
                  pause(0.05)
              end
            end

            %Releases the hold on the graph
            hold (app.UIAxes, 'off');
           
            %Displays to user: time traveled, max height, and total
            %distance
            app.TimeTraveledLabel.Text = "Time Traveled: " + round(app.time,2) + "s";
            app.MaximumHeightLabel.Text = "Maximum Height: " + round(ymax,2) + "m";
            app.TotalDistanceLabel.Text = "Total Distance: " + round(xmax,2) + "m";

            %Re-enables the simulate button
            app.SimulateButton.Enable = "on";
        end

        % Button pushed function: LoadGraphButton
        function LoadGraphButtonPushed(app, event)
            %Turns the legend off because if you want to run the example
            %simulations again, the legend would have unwanted results
            legend(app.VelocityGraphPlot, 'off')

            %Sets Gravity and Launch Angle Constant
            app.GC = 9.81;
            app.LA = 45;

            %Loops animation for each example
            for j = 1:3
            %Pulls Initial Velocity values from example array
            app.IV = app.ex_IV_arr(1,j);

            %Calculate Horizontal and Vertical Components
            Vx = app.IV * cos(deg2rad(app.LA));
            Vy = app.IV * sin(deg2rad(app.LA));

            %Create time array
            app.time = Time_Function(app.IV,app.LA,app.GC);
            t = 0:0.05:app.time;

            %Holds graph dimensions constant
            xlim(app.VelocityGraphPlot, [0 25])
            ylim(app.VelocityGraphPlot, [0 7])

            %Create x and y arrays
            x = Vx .* t;
            y = (Vy .* t) - (0.5 * app.GC .* t.^2);

            %Switch case that regraphs previous trajectories when the new
            %trajectory is being ran through ("hold on" does not work in
            %this situation as all it holds is the current trajectory)

            switch j
                case 1
                plot(app.VelocityGraphPlot,x,y)
                case 2
                [X1,Y1] = ProjectileGraphing(5,45,9.81);
                plot(app.VelocityGraphPlot,X1,Y1,x,y);
                case 3
                [X1,Y1] = ProjectileGraphing(5,45,9.81);
                [X2,Y2] = ProjectileGraphing(10,45,9.81);
                plot(app.VelocityGraphPlot,X1,Y1,X2,Y2,x,y);
            end


            %Holds graph to retain trajectory
            hold (app.VelocityGraphPlot, 'on');

            %Creates the point that will move along the trajectory, "-or"
            %means: "-" a solid line, "o" a circle, "r" color red
            h = plot(app.VelocityGraphPlot,x(1),y(1),"-or");

            %Moves the point along the trajectory in real time
            for i = 2:length(t)
              if y(i) >= 0
                     set(h,'Xdata',x(i),'YData',y(i))
                  pause(0.05)
              end
            end

            %Releases the hold on the graph
            hold (app.VelocityGraphPlot, 'off');
            end

            %Enables legend and displays labels for respective graphs
            legend(app.VelocityGraphPlot, 'on')
            legend(app.VelocityGraphPlot,'5 m/s','10 m/s','15 m/s');
            

        end

        % Button pushed function: LoadGraphButton_2
        function LoadGraphButton_2Pushed(app, event)
            %Same ideas as Inital Velocity example
            
            legend(app.AngleGraphPlot, 'off')

            app.GC = 9.81;
            app.IV = 10;

            for j = 1:3
            app.LA = app.ex_LA_arr(1,j);
            Vx = app.IV * cos(deg2rad(app.LA));
            Vy = app.IV * sin(deg2rad(app.LA)); 
            app.time = Time_Function(app.IV,app.LA,app.GC);
            t = 0:0.05:app.time;
            xlim(app.AngleGraphPlot, [0 12])
            ylim(app.AngleGraphPlot, [0 5])

            %Perform Calculations
            x = Vx .* t;
            y = (Vy .* t) - (0.5 * app.GC .* t.^2);
            %Plot animated graph

            switch j
                case 1
                plot(app.AngleGraphPlot,x,y)
                case 2
                [X1,Y1] = ProjectileGraphing(10,30,9.81);
                plot(app.AngleGraphPlot,X1,Y1,x,y);
                case 3
                [X1,Y1] = ProjectileGraphing(10,30,9.81);
                [X2,Y2] = ProjectileGraphing(10,45,9.81);
                plot(app.AngleGraphPlot,X1,Y1,X2,Y2,x,y);
            end

            hold (app.AngleGraphPlot, 'on');
            h = plot(app.AngleGraphPlot,x(1),y(1),"-or");
            for i = 2:length(t)
              if y(i) >= 0
                     set(h,'Xdata',x(i),'YData',y(i))
                  pause(0.05)
              end
            end

            hold (app.AngleGraphPlot, 'off');

            
            end

            legend(app.AngleGraphPlot, 'on')
            legend(app.AngleGraphPlot,'30 Degrees','45 Degrees','75 Degrees');
        end

        % Button pushed function: LoadGraphButton_3
        function LoadGraphButton_3Pushed(app, event)
            %Same ideas as Inital Velocity example
            
            legend(app.GravityGraphPlot, 'off')

            app.IV = 10;
            app.LA = 45;

            for j = 1:3
            app.GC = app.ex_GC_arr(1,j);
            Vx = app.IV * cos(deg2rad(app.LA));
            Vy = app.IV * sin(deg2rad(app.LA)); 
            app.time = Time_Function(app.IV,app.LA,app.GC);
            t = 0:0.05:app.time;
            xlim(app.GravityGraphPlot, [0 65])
            ylim(app.GravityGraphPlot, [0 20])

            %Perform Calculations
            x = Vx .* t;
            y = (Vy .* t) - (0.5 * app.GC .* t.^2);
            %Plot animated graph

            switch j
                case 1
                plot(app.GravityGraphPlot,x,y)
                case 2
                [X1,Y1] = ProjectileGraphing(10,45,9.81);
                plot(app.GravityGraphPlot,X1,Y1,x,y);
                case 3
                [X1,Y1] = ProjectileGraphing(10,45,9.81);
                [X2,Y2] = ProjectileGraphing(10,45,3.71);
                plot(app.GravityGraphPlot,X1,Y1,X2,Y2,x,y);
            end

            hold (app.GravityGraphPlot, 'on');
            h = plot(app.GravityGraphPlot,x(1),y(1),"-or");
            for i = 2:length(t)
              if y(i) >= 0
                     set(h,'Xdata',x(i),'YData',y(i))
                  pause(0.05)
              end
            end

            hold (app.GravityGraphPlot, 'off');

            
            end

            legend(app.GravityGraphPlot, 'on')
            legend(app.GravityGraphPlot, 'Earth (9.81 m/s^2)','Mars (3.71 m/s^2)','Moon (1.63 m/s^2)');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            tab_array = [app.Q1Tab, app.Q2Tab, app.Q3Tab, app.Q4Tab, app.Q5Tab, app.Q6Tab, app.Q7Tab, app.Q8Tab, app.Q9Tab, app.Q10Tab];
            app.PreviousButton.Enable = "on";
            for i = 1:10
                if app.TabGroup.SelectedTab == tab_array(i)
                    app.TabGroup.SelectedTab = tab_array(i + 1);
                    if app.TabGroup.SelectedTab == app.Q10Tab
                        app.NextButton.Enable = "off";
                    end
                    break
                end
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            tab_array = [app.Q1Tab, app.Q2Tab, app.Q3Tab, app.Q4Tab, app.Q5Tab, app.Q6Tab, app.Q7Tab, app.Q8Tab, app.Q9Tab, app.Q10Tab];
            app.NextButton.Enable = "on";
            for i = 1:10
                if app.TabGroup.SelectedTab == tab_array(i)
                    app.TabGroup.SelectedTab = tab_array(i - 1);
                    if app.TabGroup.SelectedTab == app.Q1Tab
                        app.PreviousButton.Enable = "off";
                    end
                    break
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.Q1Tab
                 app.PreviousButton.Enable = "off";
                 app.NextButton.Enable = "on";
            elseif selectedTab == app.Q10Tab
                 app.NextButton.Enable = "off";
                 app.PreviousButton.Enable = "on";
            else
                 app.PreviousButton.Enable = "on";
                 app.NextButton.Enable = "on";
            end
        end

        % Button pushed function: DistanceNext
        function DistanceNextButtonPushed(app, event)
            app.OverviewTabGroup.SelectedTab = app.VelocityGraphTab;
        end

        % Button pushed function: VelocityGraphPrevious
        function VelocityGraphPreviousButtonPushed(app, event)
            app.OverviewTabGroup.SelectedTab = app.DistanceTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EquationsPanel = uipanel(app.UIFigure);
            app.EquationsPanel.Title = 'Equations';
            app.OverviewTabGroup = uitabgroup(app.EquationsPanel);
            app.OverviewTab = uitab(app.OverviewTabGroup);
            app.OverviewTab.Title = 'Overview';
            app.OverviewNext = uibutton(app.OverviewTab, 'push');
            app.OverviewNext.ButtonPushedFcn = createCallbackFcn(app, @PushOverviewNext, true);
            app.OverviewNext.Text = 'Next';
            app.OverviewText = uitextarea(app.OverviewTab);
            app.OverviewText.Value = {'     Basic projectile motion is an important and universal concept in physics. Imagine the path a baseball takes after it has been hit by a bat, or the path a football takes after it is thrown downfield. These paths can be accurately predicted if a few initial variables are known; initial velocity, the launch angle from the horizon, and the acceleration due to gravity. To do this, we use the basic kinematic equations but rearranged for projectile motion. In this program, we will ignore air resistance and assume that the projectile is launched from the same height it lands at.'};
            app.TextArea = uitextarea(app.OverviewTab);
            app.OverviewTitle = uieditfield(app.OverviewTab, 'text');
            app.OverviewTitle.Value = 'Projectile Motion';
            app.Image10 = uiimage(app.OverviewTab);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'Baseball.png');
            app.InitialVariablesTab = uitab(app.OverviewTabGroup);
            app.InitialVariablesTab.Title = 'InitialVariables';
            app.InitialVariablesPrevious = uibutton(app.InitialVariablesTab, 'push');
            app.InitialVariablesPrevious.ButtonPushedFcn = createCallbackFcn(app, @PushInitialVariablesPrevious, true);
            app.InitialVariablesPrevious.Text = 'Previous';
            app.InitialVariablesNext = uibutton(app.InitialVariablesTab, 'push');
            app.InitialVariablesNext.ButtonPushedFcn = createCallbackFcn(app, @PushInitialVariablesNext, true);
            app.InitialVariablesNext.Text = 'Next';
            app.OverviewTitle_2 = uieditfield(app.InitialVariablesTab, 'text');
            app.OverviewTitle_2.Value = 'Changing the Initial Values of Motion';
            app.OverviewText_2 = uitextarea(app.InitialVariablesTab);
            app.OverviewText_2.Value = {'     Before we learn how to calculate the details of a projectiles motion, is it important to visualize how changing any of the inital variables (the initial velocity, the launch angle, and the acceleration due to gravity) affects the trajectory that a projectile takes. Go through the Graph tabs to see a representation of how changing only one of these variables at a time changes the projectile''s motion.'; ''; '     The first graph shows how changing only the initial velocity affects projectile motion, as all the projectiles are launched at 45 degrees using Earth''s gravity (9.81m/s^2) as g.'; ''; '     The second graph shows how changing only the launch angle affects projectile motion, as all the projectiles are launched with an initial velocity of 10 m/s using Earth''s gravity.'; ''; '     The third graph shows how changing only the acceleration due to gravity affects projectile motion, as all the projectiles are launched with an initial velocity of 10 m/s at a 45 degree launch angle.'};
            app.EquationsTab = uitab(app.OverviewTabGroup);
            app.EquationsTab.Title = 'Equations';
            app.EquationsPrevious = uibutton(app.EquationsTab, 'push');
            app.EquationsPrevious.ButtonPushedFcn = createCallbackFcn(app, @PushEquationsPrevious, true);
            app.EquationsPrevious.Text = 'Previous';
            app.EquationsNext = uibutton(app.EquationsTab, 'push');
            app.EquationsNext.ButtonPushedFcn = createCallbackFcn(app, @PushEquationsNext, true);
            app.EquationsNext.Text = 'Next';
            app.EquationsText = uitextarea(app.EquationsTab);
            app.EquationsText.Value = {'     The kinematic equations are a set of equations that can be used to find an unknown aspect (change in position, velocity, or time) of an object''s motion if the other aspects are known. These equations can only be used if the object''s acceleration is constant; which in the case of simple projectile motion is true, as the only acceleration a projectile undergoes is the downwards acceleration due to gravity. In this program, we will be rearranging these equations so they can be solved for other variables in the equation in order to calculate the maximum height, the travel time, and the total horizontal distance achieved by a projectile. '; '     When using these equations for projectile motion, the acceleration (a) will always be replaced with negative gravity  (-g) as the acceleration due to gravity is acting downwards. Also keep in mind that x and delta-x are used to denote position, even when used in the vertical, or y, direction.'};
            app.EquationsTitle = uieditfield(app.EquationsTab, 'text');
            app.EquationsTitle.Value = 'Kinematic Equations';
            app.Image = uiimage(app.EquationsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'KinematicEquations.jpg');
            app.ComponentsTab = uitab(app.OverviewTabGroup);
            app.ComponentsTab.Title = 'Components';
            app.TextArea_8 = uitextarea(app.ComponentsTab);
            app.ComponentsPrevious = uibutton(app.ComponentsTab, 'push');
            app.ComponentsPrevious.ButtonPushedFcn = createCallbackFcn(app, @PushComponentsPrevious, true);
            app.ComponentsPrevious.Text = 'Previous';
            app.ComponentsNext = uibutton(app.ComponentsTab, 'push');
            app.ComponentsNext.ButtonPushedFcn = createCallbackFcn(app, @PushComponentsNext, true);
            app.ComponentsNext.Text = 'Next';
            app.ComponentsText = uitextarea(app.ComponentsTab);
            app.ComponentsText.Value = {'     First, when given the initial velocity and launch angle, the velocity must be broken up into component form, with the x-velocity representing the part of the velocity in the horizontal direction and the y-velocity representing the part of the of the velocity in the vertical direction. These values are calculated by using trigonometry. The x-velocity is calculated by multiplying the initial velocity by the cosine of the launch angle, while the y-velocity is calculated by multiplying the initial velocity by the sine of the launch angle. Once these components are known, the kinematic equations can be applied, and the projectile motion can be calculated. '};
            app.VelocityTitle = uieditfield(app.ComponentsTab, 'text');
            app.VelocityTitle.Value = 'Finding Velocity Components';
            app.Image2 = uiimage(app.ComponentsTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'VelocityComponents.jpg');
            app.Image9 = uiimage(app.ComponentsTab);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'ComponentsEquation.jpg');
            app.HeightTab = uitab(app.OverviewTabGroup);
            app.HeightTab.Title = 'Height';
            app.HeightPrevious = uibutton(app.HeightTab, 'push');
            app.HeightPrevious.ButtonPushedFcn = createCallbackFcn(app, @PushHeightPrevious, true);
            app.HeightPrevious.Text = 'Previous';
            app.HeightNext = uibutton(app.HeightTab, 'push');
            app.HeightNext.ButtonPushedFcn = createCallbackFcn(app, @PushHeightNext, true);
            app.HeightNext.Text = 'Next';
            app.HeightText = uitextarea(app.HeightTab);
            app.HeightText.Value = {'     The maximum height that the projectile reaches can be found by using a rearranged form of the equation: vf^2 = vi^2 + 2*a*(xf-xi). First, -g is inputted for the acceleration term, as gravity is acting downwards against the initial motion in the vertical direction. Then, by setting vf to be zero, as the projectile has no vertical velocity when it is at its maximum height, and setting xi to be zero, as we want to find the difference in height that the projectile reaches relative to its starting height, this equation can be solved for xf to give the following: xf = (vi^2)/2g. By inputting the initial y-velocity and the acceleration due to gravity, we can calculate the maximum height that the projectile reaches. Do note that only the vertical component of the initial velocity is used in this equation.'};
            app.TextArea_4 = uitextarea(app.HeightTab);
            app.HeightTitle = uieditfield(app.HeightTab, 'text');
            app.HeightTitle.Value = 'Finding Maximum Height';
            app.Image3 = uiimage(app.HeightTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'HeightParabola.jpg');
            app.Image4 = uiimage(app.HeightTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'HeightKinematic.jpg');
            app.TimeTab = uitab(app.OverviewTabGroup);
            app.TimeTab.Title = 'Time';
            app.TimePrevious = uibutton(app.TimeTab, 'push');
            app.TimePrevious.ButtonPushedFcn = createCallbackFcn(app, @PushTimePrevious, true);
            app.TimePrevious.Text = 'Previous';
            app.TimeNext = uibutton(app.TimeTab, 'push');
            app.TimeNext.ButtonPushedFcn = createCallbackFcn(app, @PushTimeNext, true);
            app.TimeNext.Text = 'Next';
            app.TimeText = uitextarea(app.TimeTab);
            app.TimeText.Value = {'     The time that the projectile spends airborne can be found by using a rearranged form of the equation: vf = vi + a*t. By setting vf to be equal to -vi, as the projectile will have the same velocity but in the opposite direction at the end of its part, as we are assuming that the projectile launching and landing positions are at the same elevation, this equation can be solved for t to give the following: t = 2vf/g. By inputting the initial y-velocity and the acceleration due to gravity, we can calculate the total time that the projectile spends airborne. Do note that once again only the vertical component of the initial velocity is used in this equation.'};
            app.TextArea_5 = uitextarea(app.TimeTab);
            app.TimeTitle = uieditfield(app.TimeTab, 'text');
            app.TimeTitle.Value = 'Finding Total Time in Motion';
            app.Image5 = uiimage(app.TimeTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'TimeParabola.jpg');
            app.Image6 = uiimage(app.TimeTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'TimeKinematic.jpg');
            app.DistanceTab = uitab(app.OverviewTabGroup);
            app.DistanceTab.Title = 'Distance';
            app.DistancePrevious = uibutton(app.DistanceTab, 'push');
            app.DistancePrevious.ButtonPushedFcn = createCallbackFcn(app, @PushDistancePrevious, true);
            app.DistancePrevious.Text = 'Previous';
            app.DistanceText = uitextarea(app.DistanceTab);
            app.DistanceText.Value = {'     Finally, the horizontal distance that the projectile travels can be found by using the equation xf = xi + vi*t. In simple projectile motion, the x-component of velocity is constant throughout the entire trajectory so there is no acceleration term in the equation. By setting xi to be zero, the equation results:   xf = vi*t. By inputting the initial x-velocity and the total airborne time, the distance can be calculated. Do note that only the horizontal component of the initial velocity is used in this equation.'; ''; '     If you are confident in your understanding of these equations and how they can be used to calculate the results of projectile motion, you may press the Continue to Simulation button to view an interactive simulation of simple projectile motion.'};
            app.TextArea_6 = uitextarea(app.DistanceTab);
            app.DistanceTitle = uieditfield(app.DistanceTab, 'text');
            app.DistanceTitle.Value = 'Finding Horizontal Distance Traveled';
            app.Image7 = uiimage(app.DistanceTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'DistanceParabola.jpg');
            app.Image8 = uiimage(app.DistanceTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'DistanceKinematic.jpg');
            app.DistanceNext = uibutton(app.DistanceTab, 'push');
            app.DistanceNext.ButtonPushedFcn = createCallbackFcn(app, @DistanceNextButtonPushed, true);
            app.DistanceNext.Text = 'Next';
            app.VelocityGraphTab = uitab(app.OverviewTabGroup);
            app.VelocityGraphTab.Title = 'VelocityGraph';
            app.VelocityGraphPlot = uiaxes(app.VelocityGraphTab);
            title(app.VelocityGraphPlot, 'Projectile Motion for Different Initial Velocities')
            xlabel(app.VelocityGraphPlot, 'Distance (m)')
            ylabel(app.VelocityGraphPlot, 'Height (m)')
            zlabel(app.VelocityGraphPlot, 'Z')
            app.VelocityGraphNext = uibutton(app.VelocityGraphTab, 'push');
            app.VelocityGraphNext.ButtonPushedFcn = createCallbackFcn(app, @PushVelocityGraphNext, true);
            app.VelocityGraphNext.Text = 'Next';
            app.LoadGraphButton = uibutton(app.VelocityGraphTab, 'push');
            app.LoadGraphButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGraphButtonPushed, true);
            app.LoadGraphButton.Text = 'Load Graph';
            app.Label_2 = uilabel(app.VelocityGraphTab);
            app.Label_2.Text = 'Note: Launch Angle is constant at 45 degrees and Acceleration due to Gravity is constant at 9.81m/s^2 (Earth''s Gravity)';
            app.VelocityGraphPrevious = uibutton(app.VelocityGraphTab, 'push');
            app.VelocityGraphPrevious.ButtonPushedFcn = createCallbackFcn(app, @VelocityGraphPreviousButtonPushed, true);
            app.VelocityGraphPrevious.Text = 'Previous';
            app.AngleGraphTab = uitab(app.OverviewTabGroup);
            app.AngleGraphTab.Title = 'AngleGraph';
            app.AngleGraphPlot = uiaxes(app.AngleGraphTab);
            title(app.AngleGraphPlot, 'Projectile Motion for Different Launch Angles')
            xlabel(app.AngleGraphPlot, 'Distance (m)')
            ylabel(app.AngleGraphPlot, 'Height (m)')
            zlabel(app.AngleGraphPlot, 'Z')
            app.AngleGraphPlot.XLim = [0 12];
            app.AngleGraphPlot.YLim = [0 5];
            app.AngleGraphNext = uibutton(app.AngleGraphTab, 'push');
            app.AngleGraphNext.ButtonPushedFcn = createCallbackFcn(app, @PushAngleGraphNext, true);
            app.AngleGraphNext.Text = 'Next';
            app.AngleGraphPrevious = uibutton(app.AngleGraphTab, 'push');
            app.AngleGraphPrevious.ButtonPushedFcn = createCallbackFcn(app, @PushAngleGraphPrevious, true);
            app.AngleGraphPrevious.Text = 'Previous';
            app.LoadGraphButton_2 = uibutton(app.AngleGraphTab, 'push');
            app.LoadGraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadGraphButton_2Pushed, true);
            app.LoadGraphButton_2.Text = 'Load Graph';
            app.Label_3 = uilabel(app.AngleGraphTab);
            app.Label_3.Text = 'Note: Initial Velocity is constant at 10m/s and Acceleration due to Gravity is constant at 9.81m/s^2 (Earth''s Gravity)';
            app.GravityGraphTab = uitab(app.OverviewTabGroup);
            app.GravityGraphTab.Title = 'GravityGraph';
            app.GravityGraphPlot = uiaxes(app.GravityGraphTab);
            title(app.GravityGraphPlot, 'Projectile Motion for Different Gravities')
            xlabel(app.GravityGraphPlot, 'Distance (m)')
            ylabel(app.GravityGraphPlot, 'Height (m)')
            zlabel(app.GravityGraphPlot, 'Z')
            app.GravityGraphPrevious = uibutton(app.GravityGraphTab, 'push');
            app.GravityGraphPrevious.ButtonPushedFcn = createCallbackFcn(app, @PushGravityGraphPrevious, true);
            app.GravityGraphPrevious.Text = 'Previous';
            app.LoadGraphButton_3 = uibutton(app.GravityGraphTab, 'push');
            app.LoadGraphButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadGraphButton_3Pushed, true);
            app.LoadGraphButton_3.Text = 'Load Graph';
            app.Label_4 = uilabel(app.GravityGraphTab);
            app.Label_4.Text = 'Note: Launch Angle is constant at 45 degrees and Initial Velocity is constant at 10m/s';
            app.ContinuetoSimulationButton = uibutton(app.EquationsPanel, 'push');
            app.ContinuetoSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @next1, true);
            app.ContinuetoSimulationButton.Text = 'Continue to Simulation';
            app.QuizPanel = uipanel(app.UIFigure);
            app.QuizPanel.Title = 'Questions';
            app.TabGroup = uitabgroup(app.QuizPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Q1Tab = uitab(app.TabGroup);
            app.Q1Tab.Title = 'Q1';
            app.XLabel = uilabel(app.Q1Tab);
            app.XLabel.Text = 'X:';
            app.Question1Label = uilabel(app.Q1Tab);
            app.Question1Label.Text = 'Question 1:';
            app.YLabel = uilabel(app.Q1Tab);
            app.YLabel.Text = 'Y:';
            app.Answer1y = uieditfield(app.Q1Tab, 'numeric');
            app.Answer1y.AllowEmpty = 'on';
            app.Answer1y.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer1y.Value = [];
            app.Answer1x = uieditfield(app.Q1Tab, 'numeric');
            app.Answer1x.AllowEmpty = 'on';
            app.Answer1x.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer1x.Value = [];
            app.question_1 = uilabel(app.Q1Tab);
            app.question_1.Text = 'If an object is thrown at a velocity of 20 m/s at an angle of 30 degrees what are the x and y components of the velocity?';
            app.Q2Tab = uitab(app.TabGroup);
            app.Q2Tab.Title = 'Q2';
            app.Question2Label = uilabel(app.Q2Tab);
            app.Question2Label.Text = 'Question 2:';
            app.XLabel_2 = uilabel(app.Q2Tab);
            app.XLabel_2.Text = 'X:';
            app.YLabel_2 = uilabel(app.Q2Tab);
            app.YLabel_2.Text = 'Y:';
            app.Answer2y = uieditfield(app.Q2Tab, 'numeric');
            app.Answer2y.AllowEmpty = 'on';
            app.Answer2y.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer2y.Value = [];
            app.Answer2x = uieditfield(app.Q2Tab, 'numeric');
            app.Answer2x.AllowEmpty = 'on';
            app.Answer2x.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer2x.Value = [];
            app.question_2 = uilabel(app.Q2Tab);
            app.question_2.Text = 'If an object is launched at a velocity of 14.67 m/s at an angle of 74.35 degrees what are the x and y components of the velocity?';
            app.Q3Tab = uitab(app.TabGroup);
            app.Q3Tab.Title = 'Q3';
            app.Question3Label = uilabel(app.Q3Tab);
            app.Question3Label.Text = 'Question 3:';
            app.XLabel_3 = uilabel(app.Q3Tab);
            app.XLabel_3.Text = 'm';
            app.Answer3m = uieditfield(app.Q3Tab, 'numeric');
            app.Answer3m.AllowEmpty = 'on';
            app.Answer3m.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer3m.Value = [];
            app.question_3 = uilabel(app.Q3Tab);
            app.question_3.Text = ' If an object launched on Earth has an initial y-component velocity of 13.5 m/s, what is the maximum height it will reach? (The acceleration due to gravity on Earth is 9.81 m/s^2.)';
            app.Q4Tab = uitab(app.TabGroup);
            app.Q4Tab.Title = 'Q4';
            app.Question4Label = uilabel(app.Q4Tab);
            app.Question4Label.Text = 'Question 4:';
            app.XLabel_11 = uilabel(app.Q4Tab);
            app.XLabel_11.Text = 'm';
            app.Answer4m = uieditfield(app.Q4Tab, 'numeric');
            app.Answer4m.AllowEmpty = 'on';
            app.Answer4m.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer4m.Value = [];
            app.question_4 = uilabel(app.Q4Tab);
            app.question_4.Text = 'If an object launched on the moon has an initial velocity of 8 m/s at an angle of 60 degrees, what is the maximum height is will reach? (The acceleration due to gravity on the moon is 1.63 m/s^2.)';
            app.Q5Tab = uitab(app.TabGroup);
            app.Q5Tab.Title = 'Q5';
            app.Question5Label = uilabel(app.Q5Tab);
            app.Question5Label.Text = 'Question 5:';
            app.XLabel_12 = uilabel(app.Q5Tab);
            app.XLabel_12.Text = 's';
            app.Answer5s = uieditfield(app.Q5Tab, 'numeric');
            app.Answer5s.AllowEmpty = 'on';
            app.Answer5s.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer5s.Value = [];
            app.question_5 = uilabel(app.Q5Tab);
            app.question_5.Text = 'If an object launched on Earth has an initial y-component velocity of 2.77 m/s, how long will the object stay airborne? (The acceleration due to gravity on Earth is 9.81 m/s^2.)';
            app.Q6Tab = uitab(app.TabGroup);
            app.Q6Tab.Title = 'Q6';
            app.Question6Label = uilabel(app.Q6Tab);
            app.Question6Label.Text = 'Question 6:';
            app.XLabel_13 = uilabel(app.Q6Tab);
            app.XLabel_13.Text = 's';
            app.Answer6s = uieditfield(app.Q6Tab, 'numeric');
            app.Answer6s.AllowEmpty = 'on';
            app.Answer6s.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer6s.Value = [];
            app.question_6 = uilabel(app.Q6Tab);
            app.question_6.Text = 'If an object launched on Mars has an initial velocity of 10.5 m/s at a launch angle of 50.3 degrees, how long will the object stay airborne? (The acceleration due to gravity on Mars is 3.7 m/s^2.)';
            app.Q7Tab = uitab(app.TabGroup);
            app.Q7Tab.Title = 'Q7';
            app.Question7Label = uilabel(app.Q7Tab);
            app.Question7Label.Text = 'Question 7:';
            app.XLabel_14 = uilabel(app.Q7Tab);
            app.XLabel_14.Text = 'm';
            app.Answer7m = uieditfield(app.Q7Tab, 'numeric');
            app.Answer7m.AllowEmpty = 'on';
            app.Answer7m.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer7m.Value = [];
            app.question_7 = uilabel(app.Q7Tab);
            app.question_7.Text = 'If an object travels for 0.16 seconds with an initial x-component velocity of 60 m/s, how far did it travel?';
            app.Q8Tab = uitab(app.TabGroup);
            app.Q8Tab.Title = 'Q8';
            app.Question8Label = uilabel(app.Q8Tab);
            app.Question8Label.Text = 'Question 8:';
            app.XLabel_15 = uilabel(app.Q8Tab);
            app.XLabel_15.Text = 'm';
            app.Answer8m = uieditfield(app.Q8Tab, 'numeric');
            app.Answer8m.AllowEmpty = 'on';
            app.Answer8m.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer8m.Value = [];
            app.question_8 = uilabel(app.Q8Tab);
            app.question_8.Text = 'If an object is launched at an initial velocity of 13.33 m/s at an angle of 45 degrees and travels for 2 seconds, how far did it travel?';
            app.Q9Tab = uitab(app.TabGroup);
            app.Q9Tab.Title = 'Q9';
            app.Question9Label = uilabel(app.Q9Tab);
            app.Question9Label.Text = 'Question 9:';
            app.XLabel_9 = uilabel(app.Q9Tab);
            app.XLabel_9.Text = 'h =';
            app.YLabel_9 = uilabel(app.Q9Tab);
            app.YLabel_9.Text = 't =';
            app.YLabel_12 = uilabel(app.Q9Tab);
            app.YLabel_12.Text = 'd =';
            app.Answer9d = uieditfield(app.Q9Tab, 'numeric');
            app.Answer9d.AllowEmpty = 'on';
            app.Answer9d.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer9d.Value = [];
            app.Answer9t = uieditfield(app.Q9Tab, 'numeric');
            app.Answer9t.AllowEmpty = 'on';
            app.Answer9t.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer9t.Value = [];
            app.Answer9h = uieditfield(app.Q9Tab, 'numeric');
            app.Answer9h.AllowEmpty = 'on';
            app.Answer9h.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer9h.Value = [];
            app.question_9 = uilabel(app.Q9Tab);
            app.question_9.Text = 'If an object launched on Earth has an initial velocity of 42.02 m/s at an angle of 69 degrees, what was the maximum height, how long did it stay airborne, and how far did it travel? (The acceleration due to gravity on Earth is 9.81 m/s^2.)';
            app.Q10Tab = uitab(app.TabGroup);
            app.Q10Tab.Title = 'Q10';
            app.Question10Label = uilabel(app.Q10Tab);
            app.Question10Label.Text = 'Question 10:';
            app.XLabel_16 = uilabel(app.Q10Tab);
            app.XLabel_16.Text = 'h =';
            app.YLabel_13 = uilabel(app.Q10Tab);
            app.YLabel_13.Text = 't =';
            app.YLabel_14 = uilabel(app.Q10Tab);
            app.YLabel_14.Text = 'd =';
            app.Answer10d = uieditfield(app.Q10Tab, 'numeric');
            app.Answer10d.AllowEmpty = 'on';
            app.Answer10d.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer10d.Value = [];
            app.Answer10t = uieditfield(app.Q10Tab, 'numeric');
            app.Answer10t.AllowEmpty = 'on';
            app.Answer10t.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer10t.Value = [];
            app.Answer10h = uieditfield(app.Q10Tab, 'numeric');
            app.Answer10h.AllowEmpty = 'on';
            app.Answer10h.ValueChangedFcn = createCallbackFcn(app, @quizAnswer, true);
            app.Answer10h.Value = [];
            app.question_10 = uilabel(app.Q10Tab);
            app.question_10.Text = 'If an object launched on Earth has an initial velocity of 10.02 m/s at an angle of 20.04 degrees, what was the maximum height, how long did it stay airborne, and how far did it travel? (The acceleration due to gravity on Earth is 9.81 m/s^2.)';
            app.SubmitButton = uibutton(app.QuizPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            app.Pleasespecifyallanswerstotwodecimalsex275Label = uilabel(app.QuizPanel);
            app.Pleasespecifyallanswerstotwodecimalsex275Label.Text = 'Please specify all answers to two decimals (ex: 2.75) ';
            app.NextButton = uibutton(app.QuizPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.QuizPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Text = 'Previous';
            app.FinalPanel = uipanel(app.UIFigure);
            app.FinalPanel.Title = 'Final';
            app.YourScoreLabel = uilabel(app.FinalPanel);
            app.YourScoreLabel.Text = 'Your Score:';
            app.scoreLabel = uilabel(app.FinalPanel);
            app.scoreLabel.Text = '10/10';
            app.GradeLabel = uilabel(app.FinalPanel);
            app.GradeLabel.Text = 'Grade:';
            app.LetterGradeLabel = uilabel(app.FinalPanel);
            app.LetterGradeLabel.Text = '(Grade)';
            app.GreatJobLabel = uilabel(app.FinalPanel);
            app.GreatJobLabel.Text = 'Great Job!';
            app.TextArea_9 = uitextarea(app.FinalPanel);
            app.TextArea_9.Value = {'Using Kinematic equations for projectile motion, we''ve successfully calculated the trajectory of an object travelling through space. As you have seen throughout this program even slightly changing the variables of initial velocity, launch angle, and the gravity constant can result in a completely different trajectory! Keep in mind that in this simulation, the conditions were ideal and there is no air resistance to account for. This subject is extremely essential in physics, and can have many real-world applications. Have fun in your future scientific endeavors and always remember to think like a physicist!'};
            app.GraphPanel = uipanel(app.UIFigure);
            app.GraphPanel.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphPanel);
            title(app.UIAxes, 'Launch Simulation')
            xlabel(app.UIAxes, 'Distance Traveled (m)')
            ylabel(app.UIAxes, 'Height (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.QuizButton = uibutton(app.GraphPanel, 'push');
            app.QuizButton.ButtonPushedFcn = createCallbackFcn(app, @next2, true);
            app.QuizButton.Text = 'Quiz';
            app.BacktoEquationsButton = uibutton(app.GraphPanel, 'push');
            app.BacktoEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @back1, true);
            app.BacktoEquationsButton.Text = 'Back to Equations';
            app.InitialVelocitymsLabel = uilabel(app.GraphPanel);
            app.InitialVelocitymsLabel.Text = 'Initial Velocity (m/s)';
            app.InitialVelocitymsEditField = uieditfield(app.GraphPanel, 'numeric');
            app.InitialVelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialVelocitymsEditFieldValueChanged, true);
            app.LaunchangledegreesLabel = uilabel(app.GraphPanel);
            app.LaunchangledegreesLabel.Text = 'Launch angle (degrees)';
            app.LaunchangledegreesEditField = uieditfield(app.GraphPanel, 'numeric');
            app.LaunchangledegreesEditField.ValueChangedFcn = createCallbackFcn(app, @LaunchangledegreesEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.GraphPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.GravitySimulationDropDownLabel = uilabel(app.GraphPanel);
            app.GravitySimulationDropDownLabel.Text = 'Gravity Simulation';
            app.GravitySimulationDropDown = uidropdown(app.GraphPanel);
            app.GravitySimulationDropDown.Items = {'Earth (9.81 m/s^2)', 'Moon (1.63 m/s^2)', 'Mars (3.71 m/s^2)', 'Jupiter (24.79 m/s^2)', 'Pluto (0.62 m/s^2)'};
            app.GravitySimulationDropDown.Value = 'Earth (9.81 m/s^2)';
            app.TotalDistanceLabel = uilabel(app.GraphPanel);
            app.TotalDistanceLabel.Text = 'Total Distance:';
            app.MaximumHeightLabel = uilabel(app.GraphPanel);
            app.MaximumHeightLabel.Text = 'Maximum Height:';
            app.TimeTraveledLabel = uilabel(app.GraphPanel);
            app.TimeTraveledLabel.Text = 'Time Traveled:';
            app.ProjectileMotionSimulatorLabel = uilabel(app.GraphPanel);
            app.ProjectileMotionSimulatorLabel.Text = 'Projectile Motion Simulator';
            app.Label = uilabel(app.GraphPanel);
            app.Label.Text = '';
            app.TextArea_7 = uitextarea(app.GraphPanel);
            app.TextArea_7.Value = {'Using your knowledge of the Kinemetic equations, you can now see in real time how the trajectory of an object in motion changes when you change its launch angle and initial velocity! Change these variables to your heart''s content and take note of the object''s maximum height, distanced traveled, and time traveled. Was it what you expected? Once you are done simulating objects in motion, select the "Quiz" button to be quizzed on what you learned. Be prepared, however, because when you enter the quiz, you cannot return to these slides!'};
            app.Domain090Label = uilabel(app.GraphPanel);
            app.Domain090Label.Text = 'Domain: (0 ≤ θ < 90)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjMotionMasterFileCommented

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end